classdef GT_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button_res     matlab.ui.control.Button
        Button_Upload  matlab.ui.control.Button
        Tree           matlab.ui.container.CheckBoxTree
        Node_rotate    matlab.ui.container.TreeNode
        Node_resize    matlab.ui.container.TreeNode
        Node_save      matlab.ui.container.TreeNode
        Field_angle    matlab.ui.control.NumericEditField
        Label_3        matlab.ui.control.Label
        Field_KY       matlab.ui.control.NumericEditField
        Label_2        matlab.ui.control.Label
        Field_KX       matlab.ui.control.NumericEditField
        Label          matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CheckedNodes = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Upload
        function Button_UploadPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; ...
                              '*.*', 'All Files (*.*)'}, '载入图像');
            if isequal(file, 0)
                disp('用户取消了文件选择');
                return;
            end
            
            fullPath = fullfile(path, file);
            img = imread(fullPath);
            cla(app.UIAxes); % 清空 UIAxes
            imshow(img, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'tight'); % 调整显示范围
            disp('图片显示成功');

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app.CheckedNodes= event.CheckedNodes;
            
        end

        % Button pushed function: Button_res
        function Button_resPushed(app, event)
            if isempty(app.UIAxes.Children)
                uialert(app.UIFigure, '请先上传图像！', '错误');
                return;
            end
            
            % 获取当前图像
            img = app.UIAxes.Children.CData; 
            
            % 初始化结果图像
            resultImg1 = [];
            resultImg2 = [];
            
            % 检查是否勾选了变换选项
            nodesText = {app.CheckedNodes.Text};
            rotateChecked = any(strcmp(nodesText, '旋转变换'));
            resizeChecked = any(strcmp(nodesText, '缩放变换'));
            saveChecked = any(strcmp(nodesText, '保存图像结果'));
            
            
            if resizeChecked
                % 获取缩放因子
                kx = app.Field_KX.Value;
                ky = app.Field_KY.Value;
                resultImg1 = Resize(img, kx, ky); 
            end
            
            if rotateChecked
                angle= app.Field_angle.Value;
                resultImg2 = rotate(img, angle); 
            end
            
            % 弹出新窗口显示图像
            newFig = figure('Name', '变换结果', 'NumberTitle', 'off'); 
            
            % 判断是否有图像需要显示
            if ~isempty(resultImg1) && ~isempty(resultImg2)
                H_W = sprintf('%dx%d', size(img, 1), size(img, 2));
                subplot(1, 3, 1),imshow(img),title(["原图",H_W]);
                
                H_W1 = sprintf('%dx%d', size(resultImg1, 1), size(resultImg1, 2));
                subplot(1, 3, 2),imshow(resultImg1),title(["缩放变换", H_W1]);
                
                subplot(1, 3, 3),imshow(resultImg2),title('旋转变换');
            elseif ~isempty(resultImg1)
                 H_W = sprintf('%dx%d', size(img, 1), size(img, 2));
                 subplot(1,2,1),imshow(img),title(["原图",H_W]);
                 
                 H_W1 = sprintf('%dx%d', size(resultImg1, 1), size(resultImg1, 2));
                 subplot(1,2,2),imshow(resultImg1),title(["缩放变换", H_W1]);
            elseif ~isempty(resultImg2)
                H_W = sprintf('%dx%d', size(img, 1), size(img, 2));
                subplot(1,2,1),imshow(img),title(["原图",H_W]);

                subplot(1,2,2),imshow(resultImg2),title('旋转变换');
            end
            
            % 保存图片
            if saveChecked
                [file, path] = uiputfile({'*.png';'*.jpg';'*.bmp'}, '保存结果图像');
                
                if file ~= 0
                    if ~isempty(resultImg1)
                        imwrite(resultImg1, fullfile(path, ['result_resize_' file])); % 保存缩放图像
                    end
                    if ~isempty(resultImg2)
                        imwrite(resultImg2, fullfile(path, ['result_rotate_' file])); % 保存旋转图像
                    end
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '几何变换';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始图像')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '高缩放倍数';
            app.Field_KX = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '宽缩放倍数';
            app.Field_KY = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '旋转角度  ';
            app.Field_angle = uieditfield(app.UIFigure, 'numeric');
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Node_rotate = uitreenode(app.Tree);
            app.Node_rotate.Text = '旋转变换';
            app.Node_resize = uitreenode(app.Tree);
            app.Node_resize.Text = '缩放变换';
            app.Node_save = uitreenode(app.Tree);
            app.Node_save.Text = '保存图像结果';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.Button_Upload = uibutton(app.UIFigure, 'push');
            app.Button_Upload.ButtonPushedFcn = createCallbackFcn(app, @Button_UploadPushed, true);
            app.Button_Upload.Text = '上传';
            app.Button_res = uibutton(app.UIFigure, 'push');
            app.Button_res.ButtonPushedFcn = createCallbackFcn(app, @Button_resPushed, true);
            app.Button_res.Text = '确认';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GT_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end