classdef noise_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab_noise                matlab.ui.container.Tab
        EditField_Param_fre      matlab.ui.control.EditField
        EditFieldLabel_3         matlab.ui.control.Label
        EditField_Param_spatial  matlab.ui.control.EditField
        EditFieldLabel_2         matlab.ui.control.Label
        DropDown_fre_type        matlab.ui.control.DropDown
        DropDown_2Label          matlab.ui.control.Label
        DropDown_spatial_type    matlab.ui.control.DropDown
        DropDownLabel            matlab.ui.control.Label
        Button_upload            matlab.ui.control.Button
        Button_res               matlab.ui.control.Button
        EditField_noise_Param    matlab.ui.control.EditField
        EditFieldLabel           matlab.ui.control.Label
        DropDown_noise_type      matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        UIAxes_res_fre           matlab.ui.control.UIAxes
        UIAxes_res_spatial       matlab.ui.control.UIAxes
        UIAxes_noise_img         matlab.ui.control.UIAxes
        UIAxes_origin            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_upload
        function Button_uploadPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; ...
                              '*.*', 'All Files (*.*)'}, '载入图像');
            if isequal(file, 0)
                disp('用户取消了文件选择');
                return;
            end
            
            fullPath = fullfile(path, file);
            img = imread(fullPath);
            cla(app.UIAxes_origin); % 清空 UIAxes
            imshow(img, 'Parent', app.UIAxes_origin);
            axis(app.UIAxes_origin, 'tight'); % 调整显示范围
            disp('图片显示成功');
        end

        % Button pushed function: Button_res
        function Button_resPushed(app, event)
            noise_type = app.DropDown_noise_type.Value;
            noise_param_input = app.EditField_noise_Param.Value;
            try
                noise_params = parseInput(noise_param_input);
            catch ME
                uialert(app.UIFigure, ['噪声参数输入错误: ', ME.message], '错误');
                return;
            end

            % 获取用户选择的空域滤波类型和输入参数
            spatial_filter_type = app.DropDown_spatial_type.Value;
            spatial_param_input = app.EditField_Param_spatial.Value;
            try
               spatial_params = parseInput(spatial_param_input);
            catch ME
                uialert(app.UIFigure, ['空域滤波参数输入错误: ', ME.message], '错误');
                return;
            end
            
           
            % 获取用户选择的频域滤波参数
            freq_param = str2double(app.EditField_Param_fre.Value);
            
            % 检查原始图像是否已上传
            img = app.UIAxes_origin.Children.CData;
            if isempty(img)
                uialert(app.UIFigure, '请先上传图像！', '错误');
                return;
            end
            
            %添加噪声
            try
                noisy_img = add_noise(img, noise_type, noise_params);
                imshow(noisy_img, 'Parent', app.UIAxes_noise_img);
            catch ME
                uialert(app.UIFigure, ['添加噪声失败: ', ME.message], '错误');
                return;
            end
            
            % 空域滤波 
            try
                spatial_filtered_img = spatial_filter(noisy_img,spatial_filter_type,spatial_params);
                imshow(spatial_filtered_img, 'Parent', app.UIAxes_res_spatial);
            catch ME
                uialert(app.UIFigure, ['空域滤波失败: ', ME.message], '错误');
                return;
            end
            
            % 频域滤波 
            try
                freq_filtered_img = frequency_filter(noisy_img, freq_param);
                imshow(freq_filtered_img, 'Parent', app.UIAxes_res_fre);
            catch ME
                uialert(app.UIFigure, ['频域滤波失败: ', ME.message], '错误');
                return;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '噪声与滤波';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_noise = uitab(app.TabGroup);
            app.Tab_noise.Title = '噪声与滤波';
            app.UIAxes_origin = uiaxes(app.Tab_noise);
            title(app.UIAxes_origin, '原始图像')
            xlabel(app.UIAxes_origin, 'X')
            ylabel(app.UIAxes_origin, 'Y')
            zlabel(app.UIAxes_origin, 'Z')
            app.UIAxes_noise_img = uiaxes(app.Tab_noise);
            title(app.UIAxes_noise_img, '添加噪声后图像')
            xlabel(app.UIAxes_noise_img, 'X')
            ylabel(app.UIAxes_noise_img, 'Y')
            zlabel(app.UIAxes_noise_img, 'Z')
            app.UIAxes_res_spatial = uiaxes(app.Tab_noise);
            title(app.UIAxes_res_spatial, '空域滤波图像')
            xlabel(app.UIAxes_res_spatial, 'X')
            ylabel(app.UIAxes_res_spatial, 'Y')
            zlabel(app.UIAxes_res_spatial, 'Z')
            app.UIAxes_res_fre = uiaxes(app.Tab_noise);
            title(app.UIAxes_res_fre, '频域滤波图像')
            xlabel(app.UIAxes_res_fre, 'X')
            ylabel(app.UIAxes_res_fre, 'Y')
            zlabel(app.UIAxes_res_fre, 'Z')
            app.Label = uilabel(app.Tab_noise);
            app.Label.Text = '噪声类型';
            app.DropDown_noise_type = uidropdown(app.Tab_noise);
            app.DropDown_noise_type.Items = {'高斯噪声', '椒盐噪声', '指数噪声'};
            app.DropDown_noise_type.Value = '高斯噪声';
            app.EditFieldLabel = uilabel(app.Tab_noise);
            app.EditFieldLabel.Text = '参数';
            app.EditField_noise_Param = uieditfield(app.Tab_noise, 'text');
            app.Button_res = uibutton(app.Tab_noise, 'push');
            app.Button_res.ButtonPushedFcn = createCallbackFcn(app, @Button_resPushed, true);
            app.Button_res.Text = '确认';
            app.Button_upload = uibutton(app.Tab_noise, 'push');
            app.Button_upload.ButtonPushedFcn = createCallbackFcn(app, @Button_uploadPushed, true);
            app.Button_upload.Text = '上传图片';
            app.DropDownLabel = uilabel(app.Tab_noise);
            app.DropDownLabel.Text = '空域滤波类型';
            app.DropDown_spatial_type = uidropdown(app.Tab_noise);
            app.DropDown_spatial_type.Items = {'均值滤波', '中值滤波', '双边滤波'};
            app.DropDown_spatial_type.Value = '均值滤波';
            app.DropDown_2Label = uilabel(app.Tab_noise);
            app.DropDown_2Label.Text = '频域滤波类型';
            app.DropDown_fre_type = uidropdown(app.Tab_noise);
            app.DropDown_fre_type.Items = {'理想低通滤波'};
            app.DropDown_fre_type.Value = '理想低通滤波';
            app.EditFieldLabel_2 = uilabel(app.Tab_noise);
            app.EditFieldLabel_2.Text = '参数';
            app.EditField_Param_spatial = uieditfield(app.Tab_noise, 'text');
            app.EditFieldLabel_3 = uilabel(app.Tab_noise);
            app.EditFieldLabel_3.Text = '参数';
            app.EditField_Param_fre = uieditfield(app.Tab_noise, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noise_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end