classdef Contrast_Img < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button_Upload  matlab.ui.control.StateButton
        Button_Res     matlab.ui.control.StateButton
        Tree           matlab.ui.container.CheckBoxTree
        Node_Linear    matlab.ui.container.TreeNode
        Node           matlab.ui.container.TreeNode
        Node_Log       matlab.ui.container.TreeNode
        Node_Exp       matlab.ui.container.TreeNode
        Node_Save      matlab.ui.container.TreeNode
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CheckedNodes = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button_Upload
        function Button_UploadValueChanged(app, event)
             [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; ...
                              '*.*', 'All Files (*.*)'}, '载入图像');
            if isequal(file, 0)
                disp('用户取消了文件选择');
                return;
            end
            
            fullPath = fullfile(path, file);
            img = imread(fullPath);
            cla(app.UIAxes); % 清空 UIAxes
            imshow(img, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'tight'); % 调整显示范围
            disp('图片显示成功');

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app.CheckedNodes= event.CheckedNodes;
            
        end

        % Value changed function: Button_Res
        function Button_ResValueChanged(app, event)
            if isempty(app.UIAxes.Children)
                uialert(app.UIFigure, '请先上传图像！', '错误');
                return;
            end
            
            % 获取图像
            img = app.UIAxes.Children.CData; 
            if size(img, 3) == 3
                grayImg = Gray(img); % 转灰度图
            else
                grayImg = img;
            end
        
            % 初始化变换图像
            resultImg1 = [];
            resultImg2 = [];
            resultImg3 = [];
            
            % 检查 app.CheckedNodes 属性中的节点勾选状态
            linearChecked = any(strcmp({app.CheckedNodes.Text}, '线性变换'));
            logChecked = any(strcmp({app.CheckedNodes.Text}, '对数变换'));
            expChecked = any(strcmp({app.CheckedNodes.Text}, '指数变换'));
            saveChecked = any(strcmp({app.CheckedNodes.Text}, '保存结果图片'));
            
            % 根据勾选状态应用变换
            if linearChecked
                resultImg1 = Linear_my(grayImg); % 调用线性变换函数
            end
            
            if logChecked
                resultImg2 = Log(grayImg); % 调用对数变换函数
            end
            
            if expChecked
                k = 0.3; 
                resultImg3 = Exp(grayImg); % 调用指数变换函数
            end
            
            % 统计勾选的复选框数量
            numChecked = linearChecked + logChecked + expChecked;
        
            % 根据复选框数量调整结果窗口的大小
            windowWidth = 300 + numChecked * 100;  % 每勾选一项，宽度增加100
            windowHeight = 400; % 高度固定
        
            % 创建新窗口
            resultFigure = uifigure('Name', '结果图像', 'Position', [500, 300, windowWidth, windowHeight]);
            
            % 计算Axes的宽度
            axesWidth = floor((windowWidth - 40) / (numChecked + 1)); % +1 为灰度图像
        
            % 显示灰度图像
            grayAxes = uiaxes(resultFigure, 'Position', [20, 20, axesWidth, windowHeight - 40]);
            title(grayAxes, '灰度图像');
            imshow(grayImg, 'Parent', grayAxes);
        
            % 按顺序显示变换后的图像
            currentX = 20 + axesWidth; % 初始X坐标
        
            if linearChecked
                linearAxes = uiaxes(resultFigure, 'Position', [currentX, 20, axesWidth, windowHeight - 40]);
                title(linearAxes, '线性变换');
                imshow(resultImg1, 'Parent', linearAxes);
                currentX = currentX + axesWidth;
            end
        
            if logChecked
                logAxes = uiaxes(resultFigure, 'Position', [currentX, 20, axesWidth, windowHeight - 40]);
                title(logAxes, '对数变换');
                imshow(resultImg2, 'Parent', logAxes);
                currentX = currentX + axesWidth;
            end
        
            if expChecked
                expAxes = uiaxes(resultFigure, 'Position', [currentX, 20, axesWidth, windowHeight - 40]);
                title(expAxes, '指数变换');
                imshow(resultImg3, 'Parent', expAxes);
            end
        
            % 保存结果图像（仅保存最后一个变换图像）
            if saveChecked
                [filename, pathname] = uiputfile('*.png', '保存结果图像');
                if isequal(filename, 0) || isequal(pathname, 0)
                    disp('用户点击了取消');
                else
                    fullpath = fullfile(pathname, filename);
                    if ~isempty(resultImg3)
                        imwrite(resultImg3, fullpath);
                    elseif ~isempty(resultImg2)
                        imwrite(resultImg2, fullpath);
                    elseif ~isempty(resultImg1)
                        imwrite(resultImg1, fullpath);
                    else
                        imwrite(grayImg, fullpath); % 如果没有勾选变换，保存灰度图
                    end
                    uialert(resultFigure, sprintf('结果已保存为 %s', fullpath), '保存成功');
                end
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '对比度增强';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始图像')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Node_Linear = uitreenode(app.Tree);
            app.Node_Linear.Text = '线性变换';
            app.Node = uitreenode(app.Tree);
            app.Node.Text = '非线性变换';
            app.Node_Log = uitreenode(app.Node);
            app.Node_Log.Text = '对数变换';
            app.Node_Exp = uitreenode(app.Node);
            app.Node_Exp.Text = '指数变换';
            app.Node_Save = uitreenode(app.Tree);
            app.Node_Save.Text = '保存结果图片';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.Button_Res = uibutton(app.UIFigure, 'state');
            app.Button_Res.ValueChangedFcn = createCallbackFcn(app, @Button_ResValueChanged, true);
            app.Button_Res.Text = '确认';
            app.Button_Upload = uibutton(app.UIFigure, 'state');
            app.Button_Upload.ValueChangedFcn = createCallbackFcn(app, @Button_UploadValueChanged, true);
            app.Button_Upload.Text = '上传';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Contrast_Img

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end