classdef app_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        DropDown  matlab.ui.control.DropDown
        Label     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Option = app.DropDown.Value;
            if strcmp(Option, "直方图")
                Histogram_app;
            elseif strcmp(Option, "灰度图片对比度增强")
                Contrast_Img;
            elseif strcmp(Option, "缩放与旋转")
                GT_app;
            elseif strcmp(Option, "加噪与滤波")
                noise_app;    
            elseif strcmp(Option, "边缘检测")
                Edge_app; 
            elseif strcmp(Option, "目标提取")
                search;
            elseif strcmp(Option, "特征提取")
                search;   
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '数字图像处理';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '功能';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'直方图', '灰度图片对比度增强', '缩放与旋转', '加噪与滤波', '边缘检测', '目标提取', '特征提取'};
            app.DropDown.Value = '直方图';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end