classdef search < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        Button_res               matlab.ui.control.Button
        Button_upload            matlab.ui.control.Button
        UIAxes_res               matlab.ui.control.UIAxes
        UIAxes_origin            matlab.ui.control.UIAxes
        Tab_2                    matlab.ui.container.Tab
        Button_res_character     matlab.ui.control.Button
        DropDown                 matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        UIAxes_target_character  matlab.ui.control.UIAxes
        UIAxes_origin_character  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_upload
        function Button_uploadPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; ...
                              '*.*', 'All Files (*.*)'}, '载入图像');
            if isequal(file, 0)
                disp('用户取消了文件选择');
                return;
            end
            
            fullPath = fullfile(path, file);
            img = imread(fullPath);
            cla(app.UIAxes_origin); % 清空 UIAxes
            imshow(img, 'Parent', app.UIAxes_origin);
            axis(app.UIAxes_origin, 'tight'); % 调整显示范围
            disp('图片显示成功');
        end

        % Button pushed function: Button_res
        function Button_resPushed(app, event)
            img = app.UIAxes_origin.Children.CData;
            if isempty(img)
                uialert(app.UIFigure, '请先上传图像！', '错误');
                return;
            end

            try
                res = lazy_snapping(img);
                imshow(res, 'Parent', app.UIAxes_res);
            catch ME
                uialert(app.UIFigure, ['目标提取失败: ', ME.message], '错误');
                return;
            end
        end

        % Button pushed function: Button_res_character
        function Button_res_characterPushed(app, event)
            img1 = app.UIAxes_origin.Children.CData;
            if isempty(img1)
                uialert(app.UIFigure, '请先上传图像！', '错误');
                return;
            end

            img2 = app.UIAxes_res.Children.CData;
            if isempty(img2)
                uialert(app.UIFigure, '请获得目标提取图像！', '错误');
                return;
            end

            Option = app.DropDown.Value;
            if strcmp(Option, "HOG")
               res1=HOG_2(img1);
               res2=HOG_2(img2);
            else
               res1=LBP(img1);
               res2=LBP(img2) ;
            end
            imshow(res1, 'Parent', app.UIAxes_origin_character); 
            imshow(res2, 'Parent', app.UIAxes_target_character);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '目标提取';
            app.UIAxes_origin = uiaxes(app.Tab);
            title(app.UIAxes_origin, '原始图像')
            xlabel(app.UIAxes_origin, 'X')
            ylabel(app.UIAxes_origin, 'Y')
            zlabel(app.UIAxes_origin, 'Z')
            app.UIAxes_res = uiaxes(app.Tab);
            title(app.UIAxes_res, '目标提取')
            xlabel(app.UIAxes_res, 'X')
            ylabel(app.UIAxes_res, 'Y')
            zlabel(app.UIAxes_res, 'Z')
            app.Button_upload = uibutton(app.Tab, 'push');
            app.Button_upload.ButtonPushedFcn = createCallbackFcn(app, @Button_uploadPushed, true);
            app.Button_upload.Text = '上传图片';
            app.Button_res = uibutton(app.Tab, 'push');
            app.Button_res.ButtonPushedFcn = createCallbackFcn(app, @Button_resPushed, true);
            app.Button_res.Text = '确认';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '特征提取';
            app.UIAxes_origin_character = uiaxes(app.Tab_2);
            title(app.UIAxes_origin_character, '原始图像特征')
            xlabel(app.UIAxes_origin_character, 'X')
            ylabel(app.UIAxes_origin_character, 'Y')
            zlabel(app.UIAxes_origin_character, 'Z')
            app.UIAxes_target_character = uiaxes(app.Tab_2);
            title(app.UIAxes_target_character, '提取目标特征')
            xlabel(app.UIAxes_target_character, 'X')
            ylabel(app.UIAxes_target_character, 'Y')
            zlabel(app.UIAxes_target_character, 'Z')
            app.Label = uilabel(app.Tab_2);
            app.Label.Text = '特征提取';
            app.DropDown = uidropdown(app.Tab_2);
            app.DropDown.Items = {'HOG', 'LBP'};
            app.DropDown.Value = 'HOG';
            app.Button_res_character = uibutton(app.Tab_2, 'push');
            app.Button_res_character.ButtonPushedFcn = createCallbackFcn(app, @Button_res_characterPushed, true);
            app.Button_res_character.Text = '确认';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = search

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end